classdef ImpedanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        X0EditField              matlab.ui.control.NumericEditField
        X0EditFieldLabel         matlab.ui.control.Label
        R0EditField              matlab.ui.control.NumericEditField
        R0EditFieldLabel         matlab.ui.control.Label
        linlamdaEditField        matlab.ui.control.NumericEditField
        linlamdaLabel            matlab.ui.control.Label
        BetaEditField            matlab.ui.control.NumericEditField
        BetaEditFieldLabel       matlab.ui.control.Label
        AngularfEditField        matlab.ui.control.NumericEditField
        AngularfEditFieldLabel   matlab.ui.control.Label
        updatevalues             matlab.ui.control.Label
        XLSlider                 matlab.ui.control.Slider
        XLSliderLabel            matlab.ui.control.Label
        RLSlider                 matlab.ui.control.Slider
        RLSliderLabel            matlab.ui.control.Label
        ImagVsLoadImpedanceAxes  matlab.ui.control.UIAxes
        RealVsLoadImpedanceAxes  matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
         function updateValuesLabel(app)
            RLValue = app.RLSlider.Value;
            XLValue = app.XLSlider.Value;
            w =app.AngularfEditField.Value;
            beta =app.BetaEditField.Value;
            l = app.linlamdaEditField.Value;
            X0 = app.X0EditField.Value;
           R0 = app.R0EditField.Value;
            Z0 = R0 + 1i * X0;
            % Get current RL and XL values
            RL = app.RLSlider.Value;
            XL = app.XLSlider.Value;

            % Calculate Zi for different load impedance values
            ZL = RL + 1i * XL;
            numerator = Z0 * (ZL + 1i*Z0*tan(2*pi*l));
            denominator = Z0 + 1i*ZL*tan(2*pi*l);
            input_impedance = numerator / denominator;
            Zi_real = real(input_impedance);
            Zi_imag = imag(input_impedance);
            app.updatevalues.Text = sprintf('RL: %.2f   XL: %.2f   Zi_Real: %.2f   Zi_imag:%.2f', RLValue, XLValue,Zi_real,Zi_imag);
        end
        function updateGraphs(app)
           
            w =app.AngularfEditField.Value;
            beta =app.BetaEditField.Value;
            l = app.linlamdaEditField.Value;
            X0 = app.X0EditField.Value;
           R0 = app.R0EditField.Value;
            Z0 = R0 + 1i * X0;
            % Get current RL and XL values
            RL = app.RLSlider.Value;
            XL = app.XLSlider.Value;

            % Calculate Zi for different load impedance values
            ZL = RL + 1i * XL;
            numerator = Z0 * (ZL + 1i*Z0*tan(2*pi*l));
            denominator = Z0 + 1i*ZL*tan(2*pi*l);
            input_impedance = numerator / denominator;
            Zi_real = real(input_impedance);
            Zi_imag = imag(input_impedance);

            % Update the plots
            
            plot3(app.RealVsLoadImpedanceAxes, RL, XL, Zi_real, 'o', 'MarkerSize', 8, 'MarkerFaceColor', '0.00,0.00,0.00');
            xlabel(app.RealVsLoadImpedanceAxes, 'Real(ZL)');
            ylabel(app.RealVsLoadImpedanceAxes, 'Imag(ZL)');
            zlabel(app.RealVsLoadImpedanceAxes, 'Real(Zi)');
            title(app.RealVsLoadImpedanceAxes, 'Rin vs. Load Impedance');
            grid(app.RealVsLoadImpedanceAxes, 'on');
            
            plot3(app.ImagVsLoadImpedanceAxes, RL, XL, Zi_imag, 'o', 'MarkerSize', 8, 'MarkerFaceColor', '0.00,0.00,0.00');
            xlabel(app.ImagVsLoadImpedanceAxes, 'Real(ZL)');
            ylabel(app.ImagVsLoadImpedanceAxes, 'Imag(ZL)');
            zlabel(app.ImagVsLoadImpedanceAxes, 'Imag(Zi)');
            title(app.ImagVsLoadImpedanceAxes, 'Xin vs. Load Impedance');
            grid(app.ImagVsLoadImpedanceAxes, 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Set initial values
            app.RLSlider.Value = 100;
            app.XLSlider.Value = 0;
            app.AngularfEditField.Value =10000;
            app.BetaEditField.Value = 1;
            app.R0EditField.Value= 60;
            app.X0EditField.Value=0;
            app.linlamdaEditField.Value=0.3183;
            % Plot initial graphs
            updateGraphs(app);
             updateValuesLabel(app);
        end

        % Value changed function: RLSlider
        function RLSliderValueChanged(app, event)
            updateGraphs(app);
            updateValuesLabel(app);
        end

        % Value changed function: XLSlider
        function XLSliderValueChanged(app, event)
           updateGraphs(app);
          updateValuesLabel(app);
        end

        % Value changed function: AngularfEditField
        function AngularfEditFieldValueChanged(app, event)
            value = app.AngularfEditField.Value;
          
        end

        % Callback function: not associated with a component
        function EnterButtonPushed(app, event)
        updateGraphs(app);
        updateValuesLabel(app);
        end

        % Value changed function: linlamdaEditField
        function linlamdaEditFieldValueChanged(app, event)
          updateGraphs(app);
          updateValuesLabel(app);
        end

        % Value changed function: R0EditField
        function R0EditFieldValueChanged(app, event)
        updateGraphs(app);
          updateValuesLabel(app);
            
        end

        % Value changed function: X0EditField
        function X0EditFieldValueChanged(app, event)
            updateGraphs(app);
          updateValuesLabel(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealVsLoadImpedanceAxes = uiaxes(app.UIFigure);
            title(app.RealVsLoadImpedanceAxes, 'RealVsLoadImpedanceAxes')
            xlabel(app.RealVsLoadImpedanceAxes, 'X')
            ylabel(app.RealVsLoadImpedanceAxes, 'Y')
            zlabel(app.RealVsLoadImpedanceAxes, 'Z')
            app.ImagVsLoadImpedanceAxes = uiaxes(app.UIFigure);
            title(app.ImagVsLoadImpedanceAxes, 'ImagVsLoadImpedanceAxes ')
            xlabel(app.ImagVsLoadImpedanceAxes, 'X')
            ylabel(app.ImagVsLoadImpedanceAxes, 'Y')
            zlabel(app.ImagVsLoadImpedanceAxes, 'Z')
            app.RLSliderLabel = uilabel(app.UIFigure);
            app.RLSliderLabel.Text = 'RLSlider';
            app.RLSlider = uislider(app.UIFigure);
            app.RLSlider.ValueChangedFcn = createCallbackFcn(app, @RLSliderValueChanged, true);
            app.XLSliderLabel = uilabel(app.UIFigure);
            app.XLSliderLabel.Text = 'XLSlider';
            app.XLSlider = uislider(app.UIFigure);
            app.XLSlider.ValueChangedFcn = createCallbackFcn(app, @XLSliderValueChanged, true);
            app.updatevalues = uilabel(app.UIFigure);
            app.updatevalues.Text = 'Label2';
            app.AngularfEditFieldLabel = uilabel(app.UIFigure);
            app.AngularfEditFieldLabel.Text = 'Angular f';
            app.AngularfEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngularfEditField.ValueChangedFcn = createCallbackFcn(app, @AngularfEditFieldValueChanged, true);
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.linlamdaLabel = uilabel(app.UIFigure);
            app.linlamdaLabel.Text = 'l (in lamda)';
            app.linlamdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.linlamdaEditField.ValueChangedFcn = createCallbackFcn(app, @linlamdaEditFieldValueChanged, true);
            app.R0EditFieldLabel = uilabel(app.UIFigure);
            app.R0EditFieldLabel.Text = 'R0';
            app.R0EditField = uieditfield(app.UIFigure, 'numeric');
            app.R0EditField.ValueChangedFcn = createCallbackFcn(app, @R0EditFieldValueChanged, true);
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.X0EditField.ValueChangedFcn = createCallbackFcn(app, @X0EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImpedanceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end